classdef CRTNcalculator < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        TabGroup                        matlab.ui.container.TabGroup
        BasicCRTNTab                    matlab.ui.container.Tab
        TotalNumberofVehiclesinsampleperiodQValueEditFieldLabel  matlab.ui.control.Label
        TotalNumberofVehiclesinsampleperiodQValueEditField  matlab.ui.control.NumericEditField
        Vehicletotalfor1hourLA101hror18hoursLA1018hrLabel  matlab.ui.control.Label
        ButtonGroup                     matlab.ui.container.ButtonGroup
        LA101hrButton                   matlab.ui.control.RadioButton
        LA1018hrButton                  matlab.ui.control.RadioButton
        ButtonGroup_2                   matlab.ui.container.ButtonGroup
        MinutesButton                   matlab.ui.control.RadioButton
        HourButton                      matlab.ui.control.RadioButton
        HoursButton                     matlab.ui.control.RadioButton
        TotallengthofsampleperiodforthevehiclescountedLabel  matlab.ui.control.Label
        HGVCorrectionTab                matlab.ui.container.Tab
        NumberoflargevehiclesHGVsEditFieldLabel  matlab.ui.control.Label
        NumberoflargevehiclesHGVsEditField  matlab.ui.control.NumericEditField
        AverageVelocityofVehiclesEditFieldLabel  matlab.ui.control.Label
        AverageVelocityofVehiclesEditField  matlab.ui.control.NumericEditField
        Switch                          matlab.ui.control.Switch
        VehicleVelocityinKilometersorMilesperHourLabel  matlab.ui.control.Label
        OtherCorrectionsTab             matlab.ui.container.Tab
        DistancefromroadinmetersEditFieldLabel  matlab.ui.control.Label
        DistancefromroadinmetersEditField  matlab.ui.control.NumericEditField
        MLabel                          matlab.ui.control.Label
        WhatangleoftheroadcanbeseenInDegreesEditFieldLabel  matlab.ui.control.Label
        WhatangleoftheroadcanbeseenInDegreesEditField  matlab.ui.control.NumericEditField
        GradientvalueasapercentageEditFieldLabel  matlab.ui.control.Label
        GradientvalueasapercentageEditField  matlab.ui.control.NumericEditField
        DistancecorrectionLabel         matlab.ui.control.Label
        AngularCorrectionLabel          matlab.ui.control.Label
        GradientCorrectionLabel         matlab.ui.control.Label
        Label                           matlab.ui.control.Label
        SingleBarrierCorrectionatOneFrequencyTab  matlab.ui.container.Tab
        HeightofBarrierMetersEditFieldLabel  matlab.ui.control.Label
        HeightofBarrierMetersEditField  matlab.ui.control.NumericEditField
        DistancefromBarriertoroadMetersEditFieldLabel  matlab.ui.control.Label
        DistancefromBarriertoroadMetersEditField  matlab.ui.control.NumericEditField
        MeasurementpositiondistancefromroadMetersEditFieldLabel  matlab.ui.control.Label
        MeasurementpositiondistancefromroadMetersEditField  matlab.ui.control.NumericEditField
        FrequencyHZEditFieldLabel       matlab.ui.control.Label
        FrequencyHZEditField            matlab.ui.control.NumericEditField
        MLabel_3                        matlab.ui.control.Label
        MLabel_4                        matlab.ui.control.Label
        HZLabel                         matlab.ui.control.Label
        DistanceabovegroundatreceivingReceiverMetersEditFieldLabel  matlab.ui.control.Label
        DistanceabovegroundatreceivingReceiverMetersEditField  matlab.ui.control.NumericEditField
        DistanceabovegroundatsourceMetersEditFieldLabel  matlab.ui.control.Label
        DistanceabovegroundatsourceMetersEditField  matlab.ui.control.NumericEditField
        MLabel_5                        matlab.ui.control.Label
        MLabel_6                        matlab.ui.control.Label
        MLabel_7                        matlab.ui.control.Label
        TotalCRTNEditFieldLabel         matlab.ui.control.Label
        TotalCRTNEditField              matlab.ui.control.NumericEditField
        CalculateButton                 matlab.ui.control.Button
        ResetallButton                  matlab.ui.control.Button
        DistanceCorrectionValueLabel    matlab.ui.control.Label
        DistanceCorrectionValueEditField  matlab.ui.control.NumericEditField
        HGVCorrectionValueEditFieldLabel  matlab.ui.control.Label
        HGVCorrectionValueEditField     matlab.ui.control.NumericEditField
        CRTNNocorrectionEditFieldLabel  matlab.ui.control.Label
        CRTNNocorrectionEditField       matlab.ui.control.NumericEditField
        AngularcorrectionValueLabel     matlab.ui.control.Label
        AngularcorrectionValueEditField  matlab.ui.control.NumericEditField
        dBLabel                         matlab.ui.control.Label
        dBLabel_2                       matlab.ui.control.Label
        dBLabel_3                       matlab.ui.control.Label
        dBLabel_4                       matlab.ui.control.Label
        dBLabel_5                       matlab.ui.control.Label
        CRTNCalculatorLabel             matlab.ui.control.Label
        BarrierCorrectionValueEditFieldLabel  matlab.ui.control.Label
        BarrierCorrectionValueEditField  matlab.ui.control.NumericEditField
        dBLabel_6                       matlab.ui.control.Label
        GradientCorrectionValueEditFieldLabel  matlab.ui.control.Label
        GradientCorrectionValueEditField  matlab.ui.control.NumericEditField
        dBLabel_7                       matlab.ui.control.Label
        HelpButton                      matlab.ui.control.Button
    end

    methods (Access = private)

        % Button pushed function: CalculateButton
        function CalculateButtonPushed(app, event)
            %%CRTN
            Q=app.TotalNumberofVehiclesinsampleperiodQValueEditField.Value; % Number of vehicles Variable

            %%HGV
            V= app.AverageVelocityofVehiclesEditField.Value;              % Velocity of Large vehicles
            P= ((app.NumberoflargevehiclesHGVsEditField.Value)/Q)*100;    % Percent of large vehicles
            MPKM=app.Switch.Value;                                        % Switch Variable Name
            
            %%Distance Correction
            R2=app.DistancefromroadinmetersEditField.Value;               % Distance from Road
            
            %%Angular Correction
            angle=app.WhatangleoftheroadcanbeseenInDegreesEditField.Value;% Angle of visible road
            
            %%Barrier
            HB=app.HeightofBarrierMetersEditField.Value;                      % Height of the barrier
            DFR=app.MeasurementpositiondistancefromroadMetersEditField.Value; % Distance from road
            RTB=app.DistancefromBarriertoroadMetersEditField.Value;           % Distance from Road to Barrier
            HRP=app.DistanceabovegroundatreceivingReceiverMetersEditField.Value; % Height above ground at measurement position
            HAS=app.DistanceabovegroundatsourceMetersEditField.Value;            % Distance Above Ground at Source
            Freq=app.FrequencyHZEditField.Value;                                 % Correction at what frequency
           
            %% Gradient
            Gradient=app.GradientvalueasapercentageEditField.Value;             %Gradient Percent value
             
            %CRTN Vehicle Estimation for periods and adjustments for LA10 1HR and 18Hr
            if (app.MinutesButton.Value)&&(app.LA101hrButton.Value)  %If Statement for all outcomes to adjust Q value
            QA=Q*12;
            elseif (app.HourButton.Value)&&(app.LA101hrButton.Value) 
            QA=Q;
            elseif (app.HoursButton.Value)&&(app.LA101hrButton.Value) % Displays a message box to indicate that the total hours is 18 but the calculation is 1.
            QA=0;                                                     % Indicates that the user should use LA10 18hrs for this calculation.
            msgbox('Unreliable for calculating LA10, please use LA10 18hr or a different approximation.');
            elseif (app.MinutesButton.Value)&&(app.LA1018hrButton.Value) 
            QA=Q*12*18;
            elseif (app.HourButton.Value)&&(app.LA1018hrButton.Value) 
            QA=Q*18;
            elseif (app.HoursButton.Value)&&(app.LA1018hrButton.Value)
            QA=Q;
            end

            %%CRTN Calculation                  
            if (QA==0)                          %If the Q value=0
                LA=0;                           %Set output value to 0 to avoid error       
            elseif(app.LA101hrButton.Value)     %set for LA1hr
                LA= 42.2 + 10*log10(QA);
            elseif (app.LA1018hrButton.Value)   %Set for LA18hr
                LA=29.1+10*log10(QA);
            end
                                             
            % HGV mph and kmh alteration
            if strcmp(MPKM,'KM/H')              %If Statement use text parameters for switch 
                VA=V;                           %VA is the same if left at the default kilometers per hour value
            elseif strcmp(MPKM,'MPH')           
                VA=V*1.609344;                  %VA is adjusted to kilometers per hour if MPH is choice
            end
            %%HGV Correction
            if (VA == 0)                        %If for if nothing is entered
                HVGC=0;
            else
                HVGC=33*log10(VA+40+(500/VA))+10*log10(1+(5*(P)/VA))-68.8;
            end
            
            %% Distance Correction
            if  (R2==0)
                DC=0;
            else
                DC=10*log10(R2/13.5);
            end
            
            %%Angular Correction
            if (angle==0)
                AC =0;
            else
                AC=10*log10(angle/180);
            end
            
            %% Barrier
            if (HB==0)
                Barrier=0;
            else
                BMP=DFR-RTB;%Distance from barrier to measurement position 
                HBFR=HB-HRP;%Difference in height between barrier and receiver position
                HBFS=HB-HAS;%Differnce in height between the barrier and source position
                CA=((BMP.^2)+(HBFR.^2)); 
                CAA=sqrt(CA); %%Total length of Hypotenuse A(From Barrier to Receiver position)
                CB=((HBFS.^2)+(RTB.^2));
                CBB=sqrt(CB); %%Total length of Hypotenuse B(From Barrier to Sourse)
                CT=(CAA+CBB)-DFR; %%Differnce between total length of the two hypotenuses 
                %minus distance away from the room
                C=343/Freq; %Calculation for wavelength
                N=(2*CT)/C; %Coefficient N
                Barrier=10*log10(3+(20*N)); %Barrier correction figure    
            end
            
            %% Gradient
             if (Gradient==0)
                 G=0;
             else
                 G=0.3*Gradient;
             end
            
            app.TotalCRTNEditField.Value=LA+HVGC-DC+AC+G-Barrier;                 
            app.CRTNNocorrectionEditField.Value=LA;
            app.HGVCorrectionValueEditField.Value=HVGC;
            app.DistanceCorrectionValueEditField.Value=DC;
            app.AngularcorrectionValueEditField.Value=AC;
            app.BarrierCorrectionValueEditField.Value=Barrier;
            app.GradientCorrectionValueEditField.Value=G;
        end

        % Callback function
        function CheckBoxValueChanged(app, event)
            
        end

        % Callback function
        function CheckBox_2ValueChanged(app, event)
            
        end

        % Value changed function: TotalCRTNEditField
        function TotalCRTNEditFieldValueChanged(app, event)
            
            
        end

        % Button pushed function: ResetallButton
        function ResetallButtonPushed(app, event)
            app.TotalNumberofVehiclesinsampleperiodQValueEditField.Value=0;
            app.NumberoflargevehiclesHGVsEditField.Value=0;
            app.AverageVelocityofVehiclesEditField.Value=0;
            app.DistancefromroadinmetersEditField.Value=0;
            app.WhatangleoftheroadcanbeseenInDegreesEditField.Value=0;
            app.TotalCRTNEditField.Value=0;                   %% Reset Ever input and output box to zero.
            app.CRTNNocorrectionEditField.Value=0;
            app.HGVCorrectionValueEditField.Value=0;
            app.DistanceCorrectionValueEditField.Value=0;
            app.AngularcorrectionValueEditField.Value=0;
            app.HeightofBarrierMetersEditField.Value=0;
            app.DistancefromBarriertoroadMetersEditField.Value=0;
            app.MeasurementpositiondistancefromroadMetersEditField.Value=0;
            app.FrequencyHZEditField.Value=0;
            app.DistanceabovegroundatreceivingReceiverMetersEditField.Value=0;
            app.DistanceabovegroundatsourceMetersEditField.Value=0;
            app.BarrierCorrectionValueEditField.Value=0;
            app.GradientvalueasapercentageEditField.Value=0;
            app.GradientvalueasapercentageEditField.Value=0;
        end

        % Value changed function: 
        % TotalNumberofVehiclesinsampleperiodQValueEditField
        function TotalNumberofVehiclesinsampleperiodQValueEditFieldValueChanged(app, event)
            
        end

        % Value changed function: Switch
        function SwitchValueChanged(app, event)
            
        end

        % Callback function
        function SampleperiodfortotalVehiclesDropDownValueChanged(app, event)
            
        end

        % Button pushed function: HelpButton
        function HelpButtonPushed(app, event)
            msgbox('Fill in each menu that you need, the total calculation will be displayed at the bottom with each calculated value also shown separately.','Help');
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.BasicCRTNTab = uitab(app.TabGroup);
            app.BasicCRTNTab.Title = 'Basic CRTN';
            app.TotalNumberofVehiclesinsampleperiodQValueEditFieldLabel = uilabel(app.BasicCRTNTab);
            app.TotalNumberofVehiclesinsampleperiodQValueEditFieldLabel.Text = 'Total Number of Vehicles in sample period (Q Value)';
            app.TotalNumberofVehiclesinsampleperiodQValueEditField = uieditfield(app.BasicCRTNTab, 'numeric');
            app.TotalNumberofVehiclesinsampleperiodQValueEditField.Limits = [0 Inf];
            app.TotalNumberofVehiclesinsampleperiodQValueEditField.RoundFractionalValues = 'on';
            app.TotalNumberofVehiclesinsampleperiodQValueEditField.ValueChangedFcn = createCallbackFcn(app, @TotalNumberofVehiclesinsampleperiodQValueEditFieldValueChanged, true);
            app.Vehicletotalfor1hourLA101hror18hoursLA1018hrLabel = uilabel(app.BasicCRTNTab);
            app.Vehicletotalfor1hourLA101hror18hoursLA1018hrLabel.Text = 'Vehicle total for 1 hour (LA10 1hr) or 18 hours (LA10 18hr) ';
            app.ButtonGroup = uibuttongroup(app.BasicCRTNTab);
            app.ButtonGroup.Tooltip = {'These options although you to pick between an LA10 18hr and LA 1hr calculation.'};
            app.LA101hrButton = uiradiobutton(app.ButtonGroup);
            app.LA101hrButton.Tooltip = {'This equation is meant to calculate the  road traffic noise level over a period of one hour.'};
            app.LA101hrButton.Text = 'LA10 1hr';
            app.LA101hrButton.Value = true;
            app.LA1018hrButton = uiradiobutton(app.ButtonGroup);
            app.LA1018hrButton.Tooltip = {'This calculation measures the road traffic noise level over a period of 18 hours.'};
            app.LA1018hrButton.Text = 'LA10 18hr';
            app.ButtonGroup_2 = uibuttongroup(app.BasicCRTNTab);
            app.ButtonGroup_2.Tooltip = {'The number of vehicles will automatically adjust depending on your sample period.'};
            app.MinutesButton = uiradiobutton(app.ButtonGroup_2);
            app.MinutesButton.Text = '5 Minutes';
            app.MinutesButton.Value = true;
            app.HourButton = uiradiobutton(app.ButtonGroup_2);
            app.HourButton.Text = '1 Hour';
            app.HoursButton = uiradiobutton(app.ButtonGroup_2);
            app.HoursButton.Text = '18 Hours';
            app.TotallengthofsampleperiodforthevehiclescountedLabel = uilabel(app.BasicCRTNTab);
            app.TotallengthofsampleperiodforthevehiclescountedLabel.Text = 'Total length of sample period for the vehicles counted';
            app.HGVCorrectionTab = uitab(app.TabGroup);
            app.HGVCorrectionTab.Title = 'HGV Correction';
            app.NumberoflargevehiclesHGVsEditFieldLabel = uilabel(app.HGVCorrectionTab);
            app.NumberoflargevehiclesHGVsEditFieldLabel.Text = 'Number of large vehicles (HGV''s)  ';
            app.NumberoflargevehiclesHGVsEditField = uieditfield(app.HGVCorrectionTab, 'numeric');
            app.NumberoflargevehiclesHGVsEditField.Limits = [0 Inf];
            app.NumberoflargevehiclesHGVsEditField.Tooltip = {'Total number of large vehicles counted within sample period.'};
            app.AverageVelocityofVehiclesEditFieldLabel = uilabel(app.HGVCorrectionTab);
            app.AverageVelocityofVehiclesEditFieldLabel.Text = 'Average Velocity of Vehicles';
            app.AverageVelocityofVehiclesEditField = uieditfield(app.HGVCorrectionTab, 'numeric');
            app.AverageVelocityofVehiclesEditField.Limits = [0 Inf];
            app.AverageVelocityofVehiclesEditField.Tooltip = {'Change the slider below to indicate Miles or Kilometers.'};
            app.Switch = uiswitch(app.HGVCorrectionTab, 'slider');
            app.Switch.Items = {'KM/H', 'MPH'};
            app.Switch.ValueChangedFcn = createCallbackFcn(app, @SwitchValueChanged, true);
            app.Switch.Value = 'KM/H';
            app.VehicleVelocityinKilometersorMilesperHourLabel = uilabel(app.HGVCorrectionTab);
            app.VehicleVelocityinKilometersorMilesperHourLabel.Text = 'Vehicle Velocity in Kilometers or Miles per Hour';
            app.OtherCorrectionsTab = uitab(app.TabGroup);
            app.OtherCorrectionsTab.Title = 'Other Corrections';
            app.DistancefromroadinmetersEditFieldLabel = uilabel(app.OtherCorrectionsTab);
            app.DistancefromroadinmetersEditFieldLabel.Text = 'Distance  from road in meters';
            app.DistancefromroadinmetersEditField = uieditfield(app.OtherCorrectionsTab, 'numeric');
            app.DistancefromroadinmetersEditField.Limits = [0 Inf];
            app.DistancefromroadinmetersEditField.Tooltip = {'How far away from the road was the measuring position?'};
            app.MLabel = uilabel(app.OtherCorrectionsTab);
            app.MLabel.Text = 'M';
            app.WhatangleoftheroadcanbeseenInDegreesEditFieldLabel = uilabel(app.OtherCorrectionsTab);
            app.WhatangleoftheroadcanbeseenInDegreesEditFieldLabel.Text = 'What angle of the road can be seen? (In Degrees)';
            app.WhatangleoftheroadcanbeseenInDegreesEditField = uieditfield(app.OtherCorrectionsTab, 'numeric');
            app.WhatangleoftheroadcanbeseenInDegreesEditField.Limits = [0 360];
            app.GradientvalueasapercentageEditFieldLabel = uilabel(app.OtherCorrectionsTab);
            app.GradientvalueasapercentageEditFieldLabel.Text = 'Gradient value as a percentage';
            app.GradientvalueasapercentageEditField = uieditfield(app.OtherCorrectionsTab, 'numeric');
            app.GradientvalueasapercentageEditField.Limits = [0 100];
            app.DistancecorrectionLabel = uilabel(app.OtherCorrectionsTab);
            app.DistancecorrectionLabel.Text = 'Distance correction';
            app.AngularCorrectionLabel = uilabel(app.OtherCorrectionsTab);
            app.AngularCorrectionLabel.Text = 'Angular Correction';
            app.GradientCorrectionLabel = uilabel(app.OtherCorrectionsTab);
            app.GradientCorrectionLabel.Text = 'Gradient Correction';
            app.Label = uilabel(app.OtherCorrectionsTab);
            app.Label.Text = '%';
            app.SingleBarrierCorrectionatOneFrequencyTab = uitab(app.TabGroup);
            app.SingleBarrierCorrectionatOneFrequencyTab.Title = 'Single Barrier Correction at One Frequency';
            app.HeightofBarrierMetersEditFieldLabel = uilabel(app.SingleBarrierCorrectionatOneFrequencyTab);
            app.HeightofBarrierMetersEditFieldLabel.Text = 'Height of Barrier (Meters)';
            app.HeightofBarrierMetersEditField = uieditfield(app.SingleBarrierCorrectionatOneFrequencyTab, 'numeric');
            app.HeightofBarrierMetersEditField.Limits = [0 Inf];
            app.DistancefromBarriertoroadMetersEditFieldLabel = uilabel(app.SingleBarrierCorrectionatOneFrequencyTab);
            app.DistancefromBarriertoroadMetersEditFieldLabel.Text = 'Distance from Barrier to road (Meters)';
            app.DistancefromBarriertoroadMetersEditField = uieditfield(app.SingleBarrierCorrectionatOneFrequencyTab, 'numeric');
            app.DistancefromBarriertoroadMetersEditField.Limits = [0 Inf];
            app.MeasurementpositiondistancefromroadMetersEditFieldLabel = uilabel(app.SingleBarrierCorrectionatOneFrequencyTab);
            app.MeasurementpositiondistancefromroadMetersEditFieldLabel.Text = 'Measurement position distance from road (Meters)';
            app.MeasurementpositiondistancefromroadMetersEditField = uieditfield(app.SingleBarrierCorrectionatOneFrequencyTab, 'numeric');
            app.MeasurementpositiondistancefromroadMetersEditField.Limits = [0 Inf];
            app.FrequencyHZEditFieldLabel = uilabel(app.SingleBarrierCorrectionatOneFrequencyTab);
            app.FrequencyHZEditFieldLabel.Text = 'Frequency (HZ)';
            app.FrequencyHZEditField = uieditfield(app.SingleBarrierCorrectionatOneFrequencyTab, 'numeric');
            app.FrequencyHZEditField.Limits = [0 20000];
            app.MLabel_3 = uilabel(app.SingleBarrierCorrectionatOneFrequencyTab);
            app.MLabel_3.Text = 'M';
            app.MLabel_4 = uilabel(app.SingleBarrierCorrectionatOneFrequencyTab);
            app.MLabel_4.Text = 'M';
            app.HZLabel = uilabel(app.SingleBarrierCorrectionatOneFrequencyTab);
            app.HZLabel.Text = 'HZ';
            app.DistanceabovegroundatreceivingReceiverMetersEditFieldLabel = uilabel(app.SingleBarrierCorrectionatOneFrequencyTab);
            app.DistanceabovegroundatreceivingReceiverMetersEditFieldLabel.Text = 'Distance above ground at receiving Receiver (Meters)';
            app.DistanceabovegroundatreceivingReceiverMetersEditField = uieditfield(app.SingleBarrierCorrectionatOneFrequencyTab, 'numeric');
            app.DistanceabovegroundatsourceMetersEditFieldLabel = uilabel(app.SingleBarrierCorrectionatOneFrequencyTab);
            app.DistanceabovegroundatsourceMetersEditFieldLabel.Text = 'Distance above ground at source (Meters)';
            app.DistanceabovegroundatsourceMetersEditField = uieditfield(app.SingleBarrierCorrectionatOneFrequencyTab, 'numeric');
            app.MLabel_5 = uilabel(app.SingleBarrierCorrectionatOneFrequencyTab);
            app.MLabel_5.Text = 'M';
            app.MLabel_6 = uilabel(app.SingleBarrierCorrectionatOneFrequencyTab);
            app.MLabel_6.Text = 'M';
            app.MLabel_7 = uilabel(app.SingleBarrierCorrectionatOneFrequencyTab);
            app.MLabel_7.Text = 'M';
            app.TotalCRTNEditFieldLabel = uilabel(app.UIFigure);
            app.TotalCRTNEditFieldLabel.Text = 'Total CRTN ';
            app.TotalCRTNEditField = uieditfield(app.UIFigure, 'numeric');
            app.TotalCRTNEditField.Limits = [-150 150];
            app.TotalCRTNEditField.ValueChangedFcn = createCallbackFcn(app, @TotalCRTNEditFieldValueChanged, true);
            app.TotalCRTNEditField.Editable = 'off';
            app.TotalCRTNEditField.Tooltip = {'Total value including all correction calculations that have been entered.'};
            app.CalculateButton = uibutton(app.UIFigure, 'push');
            app.CalculateButton.ButtonPushedFcn = createCallbackFcn(app, @CalculateButtonPushed, true);
            app.CalculateButton.Tooltip = {'Leave sections blank if you do not need correction.'};
            app.CalculateButton.Text = 'Calculate';
            app.ResetallButton = uibutton(app.UIFigure, 'push');
            app.ResetallButton.ButtonPushedFcn = createCallbackFcn(app, @ResetallButtonPushed, true);
            app.ResetallButton.Tooltip = {'Resets all values for calculator'; ' and outputs.'};
            app.ResetallButton.Text = 'Reset all';
            app.DistanceCorrectionValueLabel = uilabel(app.UIFigure);
            app.DistanceCorrectionValueLabel.Text = 'Distance (Correction Value)';
            app.DistanceCorrectionValueEditField = uieditfield(app.UIFigure, 'numeric');
            app.DistanceCorrectionValueEditField.Editable = 'off';
            app.DistanceCorrectionValueEditField.Tooltip = {'Amends for the measurement distance if more or less than 13.5 Meters away from source.'};
            app.HGVCorrectionValueEditFieldLabel = uilabel(app.UIFigure);
            app.HGVCorrectionValueEditFieldLabel.Text = 'HGV (Correction Value)';
            app.HGVCorrectionValueEditField = uieditfield(app.UIFigure, 'numeric');
            app.HGVCorrectionValueEditField.Editable = 'off';
            app.HGVCorrectionValueEditField.Tooltip = {'Ammends the CRTN calculation taking into consideration the number of Large vehicles.'};
            app.CRTNNocorrectionEditFieldLabel = uilabel(app.UIFigure);
            app.CRTNNocorrectionEditFieldLabel.Text = 'CRTN (No correction)';
            app.CRTNNocorrectionEditField = uieditfield(app.UIFigure, 'numeric');
            app.CRTNNocorrectionEditField.Editable = 'off';
            app.CRTNNocorrectionEditField.Tooltip = {'Basic Calculation with no corrections.'};
            app.AngularcorrectionValueLabel = uilabel(app.UIFigure);
            app.AngularcorrectionValueLabel.Text = 'Angular (correction Value)';
            app.AngularcorrectionValueEditField = uieditfield(app.UIFigure, 'numeric');
            app.AngularcorrectionValueEditField.Editable = 'off';
            app.dBLabel = uilabel(app.UIFigure);
            app.dBLabel.Text = 'dB';
            app.dBLabel_2 = uilabel(app.UIFigure);
            app.dBLabel_2.Text = 'dB';
            app.dBLabel_3 = uilabel(app.UIFigure);
            app.dBLabel_3.Text = 'dB';
            app.dBLabel_4 = uilabel(app.UIFigure);
            app.dBLabel_4.Text = 'dB';
            app.dBLabel_5 = uilabel(app.UIFigure);
            app.dBLabel_5.Text = 'dB';
            app.CRTNCalculatorLabel = uilabel(app.UIFigure);
            app.CRTNCalculatorLabel.Text = 'CRTN Calculator';
            app.BarrierCorrectionValueEditFieldLabel = uilabel(app.UIFigure);
            app.BarrierCorrectionValueEditFieldLabel.Text = 'Barrier (Correction Value)';
            app.BarrierCorrectionValueEditField = uieditfield(app.UIFigure, 'numeric');
            app.BarrierCorrectionValueEditField.Editable = 'off';
            app.dBLabel_6 = uilabel(app.UIFigure);
            app.dBLabel_6.Text = 'dB';
            app.GradientCorrectionValueEditFieldLabel = uilabel(app.UIFigure);
            app.GradientCorrectionValueEditFieldLabel.Text = 'Gradient (Correction Value)';
            app.GradientCorrectionValueEditField = uieditfield(app.UIFigure, 'numeric');
            app.GradientCorrectionValueEditField.Editable = 'off';
            app.dBLabel_7 = uilabel(app.UIFigure);
            app.dBLabel_7.Text = 'dB';
            app.HelpButton = uibutton(app.UIFigure, 'push');
            app.HelpButton.ButtonPushedFcn = createCallbackFcn(app, @HelpButtonPushed, true);
            app.HelpButton.Text = 'Help!!!';
        end
    end

    methods (Access = public)

        % Construct app
        function app = CRTNcalculator

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end